classdef SarcLen < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        SarcLenUIFigure                 matlab.ui.Figure
        MicroscopeCalibrationEditField  matlab.ui.control.NumericEditField
        MicroscopeCalibrationEditFieldLabel  matlab.ui.control.Label
        SarcomereLengthValueACEditField  matlab.ui.control.NumericEditField
        SarcomereLengthValueACEditFieldLabel  matlab.ui.control.Label
        SarcomereLengthValueFFTEditField  matlab.ui.control.NumericEditField
        SarcomereLengthValueFFTEditFieldLabel  matlab.ui.control.Label
        ROIforSLButton                  matlab.ui.control.Button
        LoadImageButton                 matlab.ui.control.Button
        inset_axis                      matlab.ui.control.UIAxes
        image_axis                      matlab.ui.control.UIAxes
    end


    properties (Access = private)
        image_file % Description
        roi_pos
        roi_rec
        px_cal
        roi_box
        mean_profile
    end

    methods (Access = private)
        
        function ClearDisplay(app)
            cla(app.image_axis)
            cla(app.inset_axis)

            app.SarcomereLengthValueACEditField.Value = 0;
            app.SarcomereLengthValueFFTEditField.Value = 0;

            
        end
    end
    
    methods (Access = public)
        
        function SarcomereLength(app)
            app.mean_profile = [];
            app.roi_box = [];
            app.roi_box = imcrop(app.image_file, ...
                app.roi_pos);
            imagesc(app.inset_axis,app.roi_box)
            [m,n] = size(app.roi_box);
            xlim(app.inset_axis,[0 n])
            ylim(app.inset_axis,[0 m])
            app.mean_profile = mean(app.roi_box,1);
            if app.px_cal ~=0
            app.SarcomereLengthValueACEditField.Value = SarcLenAutoCorr(app);
            app.SarcomereLengthValueFFTEditField.Value = SarcLenFFT(app);
            end
            return
            
            
        end
        
        function sl_ac = SarcLenAutoCorr(app)
            Y = app.mean_profile;
            n = numel(Y);
            lags = numel(app.mean_profile);

            Y_bar = mean(Y);

            Y_rss = sum((Y-Y_bar).^2);

            n = numel(Y);
            tot_lag = lags;
            for k = 1 : tot_lag
                Y_sum_num = 0;
                for i = k+1 : n
                    Y_sum_num = Y_sum_num + (Y(i) - Y_bar) * (Y(i-k) - Y_bar);
                end
                r_auto(k+1) = Y_sum_num/Y_rss;
            end

            r_auto(1) = sum((Y-Y_bar).*(Y-Y_bar))/Y_rss;


            [lambda,fit_parameters,r_squared,y_fit] = fit_sine_wave('y_data',r_auto,...
                'x_data',0:lags, 'min_x_index_spacing',5);
            sl_ac = fit_parameters(4) * app.px_cal;

            
        end
        
        function sl_fft = SarcLenFFT(app)
            x = 1:numel(app.mean_profile);
            Z = fft(app.mean_profile);
            fft_ix = (1:numel(app.mean_profile)-1)/(numel(app.mean_profile)*app.px_cal);
            fft_ix = [0 fft_ix];
            [max_mag,max_ix] = max(abs(Z(2:end/2+1)));
            sl_fft = numel(app.mean_profile)*app.px_cal/max_ix;

        end
    end


    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            colormap(app.SarcLenUIFigure, 'gray');
            addpath(genpath('utilities'))

        end

        % Button pushed function: LoadImageButton
        function LoadImageButtonPushed(app, event)

            filterspec = {'*.jpg;*.tif;*.png;*.gif','All Image Files'};
            [file_string,path_string] = uigetfile2(filterspec);
            if (path_string~=0)
                ClearDisplay(app)
                im_file = fullfile(path_string,file_string);
                im = imread(im_file);
                if (ndims(im)==3)
                    im = rgb2gray(im);
                end
                imagesc(app.image_axis,im);
                axis(app.image_axis,'image')
                app.image_file = im;
            end
        end

        % Button pushed function: ROIforSLButton
        function ROIforSLButtonPushed(app, event)

            app.roi_rec = drawrectangle(app.image_axis,'color','r');
            app.roi_rec.FaceAlpha = 0;
            app.roi_pos = get(app.roi_rec, 'Position');
            app.px_cal = app.MicroscopeCalibrationEditField.Value;
            SarcomereLength(app)

            addlistener(app.roi_rec,"MovingROI",@(src,evt) UpdateSL(evt));

            function UpdateSL(evt)
                app.roi_pos = get(app.roi_rec, 'Position');
                SarcomereLength(app)
            end

        end

        % Value changed function: MicroscopeCalibrationEditField
        function MicroscopeCalibrationEditFieldValueChanged(app, event)
            app.px_cal = app.MicroscopeCalibrationEditField.Value;
            if app.roi_pos
            app.SarcomereLength
            end
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.SarcLenUIFigure = uifigure('Visible', 'off');
            app.SarcLenUIFigure.Name = 'SarcLen';
            app.image_axis = uiaxes(app.SarcLenUIFigure);
            title(app.image_axis, 'Brightfield Image')
            zlabel(app.image_axis, 'Z')
            app.inset_axis = uiaxes(app.SarcLenUIFigure);
            title(app.inset_axis, 'Region of Interest (ROI)')
            zlabel(app.inset_axis, 'Z')
            app.LoadImageButton = uibutton(app.SarcLenUIFigure, 'push');
            app.LoadImageButton.ButtonPushedFcn = createCallbackFcn(app, @LoadImageButtonPushed, true);
            app.LoadImageButton.Text = 'Load Image';
            app.ROIforSLButton = uibutton(app.SarcLenUIFigure, 'push');
            app.ROIforSLButton.ButtonPushedFcn = createCallbackFcn(app, @ROIforSLButtonPushed, true);
            app.ROIforSLButton.Text = 'ROI for SL';
            app.SarcomereLengthValueFFTEditFieldLabel = uilabel(app.SarcLenUIFigure);
            app.SarcomereLengthValueFFTEditFieldLabel.Text = 'Sarcomere Length Value FFT';
            app.SarcomereLengthValueFFTEditField = uieditfield(app.SarcLenUIFigure, 'numeric');
            app.SarcomereLengthValueFFTEditField.Editable = 'off';
            app.SarcomereLengthValueACEditFieldLabel = uilabel(app.SarcLenUIFigure);
            app.SarcomereLengthValueACEditFieldLabel.Text = 'Sarcomere Length Value AC';
            app.SarcomereLengthValueACEditField = uieditfield(app.SarcLenUIFigure, 'numeric');
            app.SarcomereLengthValueACEditField.Editable = 'off';
            app.MicroscopeCalibrationEditFieldLabel = uilabel(app.SarcLenUIFigure);
            app.MicroscopeCalibrationEditFieldLabel.Text = 'Microscope Calibration';
            app.MicroscopeCalibrationEditField = uieditfield(app.SarcLenUIFigure, 'numeric');
            app.MicroscopeCalibrationEditField.ValueChangedFcn = createCallbackFcn(app, @MicroscopeCalibrationEditFieldValueChanged, true);
            % Show the figure after all components are created
            app.SarcLenUIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = SarcLen

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.SarcLenUIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.SarcLenUIFigure)
        end
    end
end